classdef rollCenterCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FrontTab                       matlab.ui.container.Tab
        LeftLowerPoint1mmEditFieldLabel  matlab.ui.control.Label
        LeftLowerPoint1mmEditField     matlab.ui.control.EditField
        LeftLowerPoint2mmEditFieldLabel  matlab.ui.control.Label
        LeftLowerPoint2mmEditField     matlab.ui.control.EditField
        LeftLowerPoint3mmEditFieldLabel  matlab.ui.control.Label
        LeftLowerPoint3mmEditField     matlab.ui.control.EditField
        LeftUpperPoint1mmEditFieldLabel  matlab.ui.control.Label
        LeftUpperPoint1mmEditField     matlab.ui.control.EditField
        LeftUpperPoint2mmEditFieldLabel  matlab.ui.control.Label
        LeftUpperPoint2mmEditField     matlab.ui.control.EditField
        LeftUpperPoint3mmEditFieldLabel  matlab.ui.control.Label
        LeftUpperPoint3mmEditField     matlab.ui.control.EditField
        RightUpperPoint1mmEditFieldLabel  matlab.ui.control.Label
        RightUpperPoint1mmEditField    matlab.ui.control.EditField
        RightUpperPoint2mmEditFieldLabel  matlab.ui.control.Label
        RightUpperPoint2mmEditField    matlab.ui.control.EditField
        RightUpperPoint3mmEditFieldLabel  matlab.ui.control.Label
        RightUpperPoint3mmEditField    matlab.ui.control.EditField
        RightLowerPoint1mmEditFieldLabel  matlab.ui.control.Label
        RightLowerPoint1mmEditField    matlab.ui.control.EditField
        RightLowerPoint2mmEditFieldLabel  matlab.ui.control.Label
        RightLowerPoint2mmEditField    matlab.ui.control.EditField
        RightLowerPoint3mmEditFieldLabel  matlab.ui.control.Label
        RightLowerPoint3mmEditField    matlab.ui.control.EditField
        RearTab                        matlab.ui.container.Tab
        LeftLowerPoint1mmEditField_2Label  matlab.ui.control.Label
        LeftLowerPoint1mmEditField_2   matlab.ui.control.EditField
        LeftLowerPoint2mmEditField_2Label  matlab.ui.control.Label
        LeftLowerPoint2mmEditField_2   matlab.ui.control.EditField
        LeftLowerPoint3mmEditField_2Label  matlab.ui.control.Label
        LeftLowerPoint3mmEditField_2   matlab.ui.control.EditField
        LeftUpperPoint1mmEditField_2Label  matlab.ui.control.Label
        LeftUpperPoint1mmEditField_2   matlab.ui.control.EditField
        LeftUpperPoint2mmEditField_2Label  matlab.ui.control.Label
        LeftUpperPoint2mmEditField_2   matlab.ui.control.EditField
        LeftUpperPoint3mmEditField_2Label  matlab.ui.control.Label
        LeftUpperPoint3mmEditField_2   matlab.ui.control.EditField
        RightUpperPoint1mmEditField_2Label  matlab.ui.control.Label
        RightUpperPoint1mmEditField_2  matlab.ui.control.EditField
        RightUpperPoint2mmEditField_2Label  matlab.ui.control.Label
        RightUpperPoint2mmEditField_2  matlab.ui.control.EditField
        RightUpperPoint3mmEditField_2Label  matlab.ui.control.Label
        RightUpperPoint3mmEditField_2  matlab.ui.control.EditField
        RightLowerPoint1mmEditField_2Label  matlab.ui.control.Label
        RightLowerPoint1mmEditField_2  matlab.ui.control.EditField
        RightLowerPoint2mmEditField_2Label  matlab.ui.control.Label
        RightLowerPoint2mmEditField_2  matlab.ui.control.EditField
        RightLowerPoint3mmEditField_2Label  matlab.ui.control.Label
        RightLowerPoint3mmEditField_2  matlab.ui.control.EditField
        ResultButton                   matlab.ui.control.Button
        WheelbasemmEditFieldLabel      matlab.ui.control.Label
        WheelbasemmEditField           matlab.ui.control.NumericEditField
        TireRadiusmmEditFieldLabel     matlab.ui.control.Label
        TireRadiusmmEditField          matlab.ui.control.NumericEditField
        TrackWidthmmEditFieldLabel     matlab.ui.control.Label
        TrackWidthmmEditField          matlab.ui.control.NumericEditField
        TireWidthmmEditFieldLabel      matlab.ui.control.Label
        TireWidthmmEditField           matlab.ui.control.NumericEditField
        CenterofGravitymmCGLabel       matlab.ui.control.Label
        CenterofGravitymmCGEditField   matlab.ui.control.EditField
        VehicleRollCenterCalculatorLabel  matlab.ui.control.Label
        InstantenousCentermmLabel      matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        IC1Label_2                     matlab.ui.control.Label
        IC2Label                       matlab.ui.control.Label
        IC3Label                       matlab.ui.control.Label
        IC4Label                       matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        RollCentermmLabel              matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        DistancebetweenRCandCGmmLabel  matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        RCLabel                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("HelperFunction\");
        end

        % Button pushed function: ResultButton
        function ResultButtonPushed2(app, event)
cla(app.UIAxes)

% Get each parameter of Tire from inputs of App GUI
TrackWidth = app.TrackWidthmmEditField.Value;
WheelBase = app.WheelbasemmEditField.Value;
TireWidth = app.TireWidthmmEditField.Value;
TireRadius = app.TireRadiusmmEditField.Value;

% Get X,Y,Z coordinate of tire data
[Xfr,Yfr,Zfr,Xfl,Yfl,Zfl,Xrr,Yrr,Zrr,Xrl,Yrl,Zrl] = getTireData(TrackWidth,WheelBase,TireWidth,TireRadius);

% Plot Tire
surf(app.UIAxes,Yfr,Zfr,Xfr,'facecolor','k','LineStyle','none');
hold (app.UIAxes,"on")
surf(app.UIAxes,Yfl,Zfl,Xfl,'facecolor','k','LineStyle','none');
surf(app.UIAxes,Yrr,Zrr,Xrr,'facecolor','k','LineStyle','none');
surf(app.UIAxes,Yrl,Zrl,Xrl,'facecolor','k','LineStyle','none');
fill3(app.UIAxes,Yfr(1,:),Zfr(1,:),Xfr(1,:),'w')
fill3(app.UIAxes,Yfr(2,:),Zfr(2,:),Xfr(2,:),'w')
fill3(app.UIAxes,Yfl(1,:),Zfl(1,:),Xfl(1,:),'w')
fill3(app.UIAxes,Yfl(2,:),Zfl(2,:),Xfl(2,:),'w')
fill3(app.UIAxes,Yrr(1,:),Zrr(1,:),Xrr(1,:),'w')
fill3(app.UIAxes,Yrr(2,:),Zrr(2,:),Xrr(2,:),'w')
fill3(app.UIAxes,Yrl(1,:),Zrl(1,:),Xrl(1,:),'w')
fill3(app.UIAxes,Yrl(2,:),Zrl(2,:),Xrl(2,:),'w')

axis (app.UIAxes,"equal")
view(app.UIAxes,[-60,30])

% Get X, Y, Z coordinate of Suspension Connecting Points from the Inputs of App GUI
Front_Left_Upper_Point1 = getNum(app.LeftUpperPoint1mmEditField.Value); 
Front_Left_Upper_Point2 = getNum(app.LeftUpperPoint2mmEditField.Value);
Front_Left_Upper_Point3 = getNum(app.LeftUpperPoint3mmEditField.Value);
Front_Left_Lower_Point4 = getNum(app.LeftLowerPoint1mmEditField.Value);
Front_Left_Lower_Point5 = getNum(app.LeftLowerPoint2mmEditField.Value);
Front_Left_Lower_Point6 = getNum(app.LeftLowerPoint3mmEditField.Value);
Front_Right_Upper_Point1 = getNum(app.RightUpperPoint1mmEditField.Value); 
Front_Right_Upper_Point2 = getNum(app.RightUpperPoint2mmEditField.Value); 
Front_Right_Upper_Point3 = getNum(app.RightUpperPoint3mmEditField.Value); 
Front_Right_Lower_Point4 = getNum(app.RightLowerPoint1mmEditField.Value);
Front_Right_Lower_Point5 = getNum(app.RightLowerPoint2mmEditField.Value);
Front_Right_Lower_Point6 = getNum(app.RightLowerPoint3mmEditField.Value);

% Combine point 2 and 3 into New Point 1
% Combine point 5 and 6 into New Point 4
% Calculate the location of Instanteneous Center points
[X_Front_Left_Upper,Y_Front_Left_Upper,Z_Front_Left_Upper,X_Front_Left_Lower,Y_Front_Left_Lower,Z_Front_Left_Lower,X_IC1,Y_IC1,Z_IC1] = getIC(Front_Left_Upper_Point1,Front_Left_Upper_Point2,Front_Left_Upper_Point3,Front_Left_Lower_Point4,Front_Left_Lower_Point5,Front_Left_Lower_Point6);
[X_Front_Right_Upper,Y_Front_Right_Upper,Z_Front_Right_Upper,X_Front_Right_Lower,Y_Front_Right_Lower,Z_Front_Right_Lower,X_IC2,Y_IC2,Z_IC2] = getIC(Front_Right_Upper_Point1,Front_Right_Upper_Point2,Front_Right_Upper_Point3,Front_Right_Lower_Point4,Front_Right_Lower_Point5,Front_Right_Lower_Point6);

% Plot line between Point 1 and New Point 1,
% Plot line between Point 4 and New Point 4,
% Plot Lines which are extended to IC point
plot3(app.UIAxes,X_Front_Left_Upper,Y_Front_Left_Upper,Z_Front_Left_Upper,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Front_Left_Lower,Y_Front_Left_Lower,Z_Front_Left_Lower,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Front_Right_Upper,Y_Front_Right_Upper,Z_Front_Right_Upper,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Front_Right_Lower,Y_Front_Right_Lower,Z_Front_Right_Lower,'LineWidth',2,'Color','b')

% Plot Instantaneous Center points
plot3(app.UIAxes,X_IC1,Y_IC1,Z_IC1,'r^')
plot3(app.UIAxes,X_IC2,Y_IC2,Z_IC2,'r^')
text(app.UIAxes,X_IC1,Y_IC1,Z_IC1,'IC1','VerticalAlignment',"bottom")
text(app.UIAxes,X_IC2,Y_IC2,Z_IC2,'IC2','VerticalAlignment',"bottom")

% Plot Lines between Instantaneous Center points and Tire-Ground Contact points 
X_IC1_TireGround = [X_IC1 0];
Y_IC1_TireGround = [Y_IC1 TrackWidth/2];
Z_IC1_TireGround = [Z_IC1 0];
plot3(app.UIAxes,X_IC1_TireGround,Y_IC1_TireGround,Z_IC1_TireGround,'b-.')
X_IC2_TireGround = [X_IC2 0];
Y_IC2_TireGround = [Y_IC2 -TrackWidth/2];
Z_IC2_TireGround = [Z_IC2 0];
plot3(app.UIAxes,X_IC2_TireGround,Y_IC2_TireGround,Z_IC2_TireGround,'b-.')

% Find the intersection, Roll Center point, of the two lines
[x_RC_Front,y_RC_Front,z_RC_Front] = getRC(X_IC1_TireGround,Y_IC1_TireGround,Z_IC1_TireGround,X_IC2_TireGround,Y_IC2_TireGround,Z_IC2_TireGround);

% Plot the front Roll Center Points
plot3(app.UIAxes,x_RC_Front,y_RC_Front,z_RC_Front,'r*')


% Get Array(numbers) of Suspension Connecting Place from the App Inputs
Rear_Left_Upper_Point1 = getNum(app.LeftUpperPoint1mmEditField_2.Value); 
Rear_Left_Upper_Point2 = getNum(app.LeftUpperPoint2mmEditField_2.Value); 
Rear_Left_Upper_Point3 = getNum(app.LeftUpperPoint3mmEditField_2.Value); 
Rear_Left_Lower_Point1 = getNum(app.LeftLowerPoint1mmEditField_2.Value); 
Rear_Left_Lower_Point2 = getNum(app.LeftLowerPoint2mmEditField_2.Value); 
Rear_Left_Lower_Point3 = getNum(app.LeftLowerPoint3mmEditField_2.Value); 
Rear_Right_Upper_Point1 = getNum(app.RightUpperPoint1mmEditField_2.Value); 
Rear_Right_Upper_Point2 = getNum(app.RightUpperPoint2mmEditField_2.Value); 
Rear_Right_Upper_Point3 = getNum(app.RightUpperPoint3mmEditField_2.Value); 
Rear_Right_Lower_Point1 = getNum(app.RightLowerPoint1mmEditField_2.Value); 
Rear_Right_Lower_Point2 = getNum(app.RightLowerPoint2mmEditField_2.Value); 
Rear_Right_Lower_Point3 = getNum(app.RightLowerPoint3mmEditField_2.Value); 


% Combine point 2 and 3 into New Point 1
% Combine point 5 and 6 into New Point 4
% Calculate the location of Instanteneous Center points
[X_Rear_Left_Upper,Y_Rear_Left_Upper,Z_Rear_Left_Upper,X_Rear_Left_Lower,Y_Rear_Left_Lower,Z_Rear_Left_Lower,X_IC3,Y_IC3,Z_IC3] = getIC(Rear_Left_Upper_Point1,Rear_Left_Upper_Point2,Rear_Left_Upper_Point3,Rear_Left_Lower_Point1,Rear_Left_Lower_Point2,Rear_Left_Lower_Point3);
[X_Rear_Right_Upper,Y_Rear_Right_Upper,Z_Rear_Right_Upper,X_Rear_Right_Lower,Y_Rear_Right_Lower,Z_Rear_Right_Lower,X_IC4,Y_IC4,Z_IC4] = getIC(Rear_Right_Upper_Point1,Rear_Right_Upper_Point2,Rear_Right_Upper_Point3,Rear_Right_Lower_Point1,Rear_Right_Lower_Point2,Rear_Right_Lower_Point3);

% Plot line between Point 1 and New Point 1,
% Plot line between Point 4 and New Point 4,
% Plot Lines which are extended to IC point
plot3(app.UIAxes,X_Rear_Left_Upper,Y_Rear_Left_Upper,Z_Rear_Left_Upper,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Rear_Left_Lower,Y_Rear_Left_Lower,Z_Rear_Left_Lower,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Rear_Right_Upper,Y_Rear_Right_Upper,Z_Rear_Right_Upper,'LineWidth',2,'Color','b')
plot3(app.UIAxes,X_Rear_Right_Lower,Y_Rear_Right_Lower,Z_Rear_Right_Lower,'LineWidth',2,'Color','b')

% Plot Instantaneous Center points
plot3(app.UIAxes,X_IC3,Y_IC3,Z_IC3,'r^')
plot3(app.UIAxes,X_IC4,Y_IC4,Z_IC4,'r^')
text(app.UIAxes,X_IC3,Y_IC3,Z_IC3,'IC3','VerticalAlignment',"bottom")
text(app.UIAxes,X_IC4,Y_IC4,Z_IC4,'IC4','VerticalAlignment',"bottom")

% Plot Lines between Instantaneous Center points and Tire-Ground Contact points 
X_IC3_TireGround = [X_IC3 0];
Y_IC3_TireGround = [Y_IC3 TrackWidth/2];
Z_IC3_TireGround = [Z_IC3 0];
plot3(app.UIAxes,X_Rear_Left_Upper,Y_IC3_TireGround,Z_IC3_TireGround,'b-.')
X_IC4_TireGround = [X_IC4 0];
Y_IC4_TireGround = [Y_IC4 -TrackWidth/2];
Z_IC4_TireGround = [Z_IC4 0];
plot3(app.UIAxes,X_Rear_Left_Upper,Y_IC4_TireGround,Z_IC4_TireGround,'b-.')

% Find the intersection, Roll Center point, of the two lines
[x_RC_Rear,y_RC_Rear,z_RC_Rear] = getRC(X_IC3_TireGround,Y_IC3_TireGround,Z_IC3_TireGround,X_IC4_TireGround,Y_IC4_TireGround,Z_IC4_TireGround);

% Plot the front Roll Center Points
plot3(app.UIAxes,x_RC_Rear,y_RC_Rear,z_RC_Rear,'r*')


% Plot Center of Gravity 
CG = getNum(app.CenterofGravitymmCGEditField.Value); 
plot3(app.UIAxes,CG(1),CG(2),CG(3),'ko')
text(app.UIAxes,CG(1),CG(2),CG(3),'   CG',"HorizontalAlignment","left")

% Output Instantaneous Center to app GUI
IC1_Output = num2str(X_IC1,4)+","+num2str(Y_IC1,4)+","+num2str(Z_IC1,4);
IC2_Output = num2str(X_IC2,4)+","+num2str(Y_IC2,4)+","+num2str(Z_IC2,4);
IC3_Output = num2str(X_IC3,4)+","+num2str(Y_IC3,4)+","+num2str(Z_IC3,4);
IC4_Output = num2str(X_IC4,4)+","+num2str(Y_IC4,4)+","+num2str(Z_IC4,4);
app.Label.Text = IC1_Output;
app.Label_2.Text = IC2_Output;
app.Label_3.Text = IC3_Output;
app.Label_4.Text = IC4_Output;

% Output Roll Center to app GUI
RC_X = (x_RC_Front+x_RC_Rear)/2;
RC_Y = (y_RC_Front+y_RC_Rear)/2;
RC_Z = (z_RC_Front+z_RC_Rear)/2;
RC_Output = num2str(RC_X,4)+","+num2str(RC_Y,4)+","+num2str(RC_Z,4);
plot3(app.UIAxes,RC_X,RC_Y,RC_Z,'b*')
text(app.UIAxes,RC_X,RC_Y,RC_Z,'RC   ',"HorizontalAlignment","right")
app.Label_5.Text = RC_Output;

% Calculate and output distance between Center of Gravity and Roll Center Height
app.Label_6.Text=num2str(abs(CG(3)-RC_Z),3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrontTab = uitab(app.TabGroup);
            app.FrontTab.Title = 'Front';
            app.LeftLowerPoint1mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftLowerPoint1mmEditFieldLabel.Text = 'Left Lower Point 1 [mm]';
            app.LeftLowerPoint1mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftLowerPoint1mmEditField.Value = '0,675,100';
            app.LeftLowerPoint2mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftLowerPoint2mmEditFieldLabel.Text = 'Left Lower Point 2 [mm]';
            app.LeftLowerPoint2mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftLowerPoint2mmEditField.Value = '-200,175,170';
            app.LeftLowerPoint3mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftLowerPoint3mmEditFieldLabel.Text = 'Left Lower Point 3 [mm]';
            app.LeftLowerPoint3mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftLowerPoint3mmEditField.Value = '200,175,170';
            app.LeftUpperPoint1mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftUpperPoint1mmEditFieldLabel.Text = 'Left Upper Point 1 [mm]';
            app.LeftUpperPoint1mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftUpperPoint1mmEditField.Value = '0,675,400';
            app.LeftUpperPoint2mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftUpperPoint2mmEditFieldLabel.Text = 'Left Upper Point 2 [mm]';
            app.LeftUpperPoint2mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftUpperPoint2mmEditField.Value = '-200,175,400';
            app.LeftUpperPoint3mmEditFieldLabel = uilabel(app.FrontTab);
            app.LeftUpperPoint3mmEditFieldLabel.Text = 'Left Upper Point 3 [mm]';
            app.LeftUpperPoint3mmEditField = uieditfield(app.FrontTab, 'text');
            app.LeftUpperPoint3mmEditField.Value = '200,175,400';
            app.RightUpperPoint1mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightUpperPoint1mmEditFieldLabel.Text = 'Right Upper Point 1 [mm]';
            app.RightUpperPoint1mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightUpperPoint1mmEditField.Value = '0,-675,400';
            app.RightUpperPoint2mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightUpperPoint2mmEditFieldLabel.Text = 'Right Upper Point 2 [mm]';
            app.RightUpperPoint2mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightUpperPoint2mmEditField.Value = '-200,-175,400';
            app.RightUpperPoint3mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightUpperPoint3mmEditFieldLabel.Text = 'Right Upper Point 3 [mm]';
            app.RightUpperPoint3mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightUpperPoint3mmEditField.Value = '200,-175,400';
            app.RightLowerPoint1mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightLowerPoint1mmEditFieldLabel.Text = 'Right Lower Point 1 [mm]';
            app.RightLowerPoint1mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightLowerPoint1mmEditField.Value = '0,-675,100';
            app.RightLowerPoint2mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightLowerPoint2mmEditFieldLabel.Text = 'Right Lower Point 2 [mm]';
            app.RightLowerPoint2mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightLowerPoint2mmEditField.Value = '-200,-175,170';
            app.RightLowerPoint3mmEditFieldLabel = uilabel(app.FrontTab);
            app.RightLowerPoint3mmEditFieldLabel.Text = 'Right Lower Point 3 [mm]';
            app.RightLowerPoint3mmEditField = uieditfield(app.FrontTab, 'text');
            app.RightLowerPoint3mmEditField.Value = '200,-175,170';
            app.RearTab = uitab(app.TabGroup);
            app.RearTab.Title = 'Rear';
            app.LeftLowerPoint1mmEditField_2Label = uilabel(app.RearTab);
            app.LeftLowerPoint1mmEditField_2Label.Text = 'Left Lower Point 1 [mm]';
            app.LeftLowerPoint1mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftLowerPoint1mmEditField_2.Value = '2800,675,100';
            app.LeftLowerPoint2mmEditField_2Label = uilabel(app.RearTab);
            app.LeftLowerPoint2mmEditField_2Label.Text = 'Left Lower Point 2 [mm]';
            app.LeftLowerPoint2mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftLowerPoint2mmEditField_2.Value = '2600,175,170';
            app.LeftLowerPoint3mmEditField_2Label = uilabel(app.RearTab);
            app.LeftLowerPoint3mmEditField_2Label.Text = 'Left Lower Point 3 [mm]';
            app.LeftLowerPoint3mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftLowerPoint3mmEditField_2.Value = '3000,175,170';
            app.LeftUpperPoint1mmEditField_2Label = uilabel(app.RearTab);
            app.LeftUpperPoint1mmEditField_2Label.Text = 'Left Upper Point 1 [mm]';
            app.LeftUpperPoint1mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftUpperPoint1mmEditField_2.Value = '2800,675,400';
            app.LeftUpperPoint2mmEditField_2Label = uilabel(app.RearTab);
            app.LeftUpperPoint2mmEditField_2Label.Text = 'Left Upper Point 2 [mm]';
            app.LeftUpperPoint2mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftUpperPoint2mmEditField_2.Value = '2600,175,400';
            app.LeftUpperPoint3mmEditField_2Label = uilabel(app.RearTab);
            app.LeftUpperPoint3mmEditField_2Label.Text = 'Left Upper Point 3 [mm]';
            app.LeftUpperPoint3mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.LeftUpperPoint3mmEditField_2.Value = '3000,175,400';
            app.RightUpperPoint1mmEditField_2Label = uilabel(app.RearTab);
            app.RightUpperPoint1mmEditField_2Label.Text = 'Right Upper Point 1 [mm]';
            app.RightUpperPoint1mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightUpperPoint1mmEditField_2.Value = '2800,-675,400';
            app.RightUpperPoint2mmEditField_2Label = uilabel(app.RearTab);
            app.RightUpperPoint2mmEditField_2Label.Text = 'Right Upper Point 2 [mm]';
            app.RightUpperPoint2mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightUpperPoint2mmEditField_2.Value = '2600,-175,400';
            app.RightUpperPoint3mmEditField_2Label = uilabel(app.RearTab);
            app.RightUpperPoint3mmEditField_2Label.Text = 'Right Upper Point 3 [mm]';
            app.RightUpperPoint3mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightUpperPoint3mmEditField_2.Value = '3000,-175,400';
            app.RightLowerPoint1mmEditField_2Label = uilabel(app.RearTab);
            app.RightLowerPoint1mmEditField_2Label.Text = 'Right Lower Point 1 [mm]';
            app.RightLowerPoint1mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightLowerPoint1mmEditField_2.Value = '2800,-675,100';
            app.RightLowerPoint2mmEditField_2Label = uilabel(app.RearTab);
            app.RightLowerPoint2mmEditField_2Label.Text = 'Right Lower Point 2 [mm]';
            app.RightLowerPoint2mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightLowerPoint2mmEditField_2.Value = '2600,-175,170';
            app.RightLowerPoint3mmEditField_2Label = uilabel(app.RearTab);
            app.RightLowerPoint3mmEditField_2Label.Text = 'Right Lower Point 3 [mm]';
            app.RightLowerPoint3mmEditField_2 = uieditfield(app.RearTab, 'text');
            app.RightLowerPoint3mmEditField_2.Value = '3000,-175,170';
            app.ResultButton = uibutton(app.UIFigure, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed2, true);
            app.ResultButton.Text = 'Result';
            app.WheelbasemmEditFieldLabel = uilabel(app.UIFigure);
            app.WheelbasemmEditFieldLabel.Text = {'Wheelbase'; '[mm]'};
            app.WheelbasemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelbasemmEditField.Value = 2800;
            app.TireRadiusmmEditFieldLabel = uilabel(app.UIFigure);
            app.TireRadiusmmEditFieldLabel.Text = {'Tire Radius'; '[mm]'};
            app.TireRadiusmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TireRadiusmmEditField.Value = 300;
            app.TrackWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.TrackWidthmmEditFieldLabel.Text = {'Track Width '; '[mm]'};
            app.TrackWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrackWidthmmEditField.Value = 1600;
            app.TireWidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.TireWidthmmEditFieldLabel.Text = {'Tire Width'; '[mm]'};
            app.TireWidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TireWidthmmEditField.Value = 250;
            app.CenterofGravitymmCGLabel = uilabel(app.UIFigure);
            app.CenterofGravitymmCGLabel.Text = {'Center of Gravity  [mm]'; ''; 'CG:'};
            app.CenterofGravitymmCGEditField = uieditfield(app.UIFigure, 'text');
            app.CenterofGravitymmCGEditField.Value = '1400,0,180';
            app.VehicleRollCenterCalculatorLabel = uilabel(app.UIFigure);
            app.VehicleRollCenterCalculatorLabel.Text = 'Vehicle Roll Center Calculator';
            app.InstantenousCentermmLabel = uilabel(app.UIFigure);
            app.InstantenousCentermmLabel.Text = {'Instantenous'; 'Center [mm]'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.IC1Label_2 = uilabel(app.UIFigure);
            app.IC1Label_2.Text = 'IC1:';
            app.IC2Label = uilabel(app.UIFigure);
            app.IC2Label.Text = 'IC2:';
            app.IC3Label = uilabel(app.UIFigure);
            app.IC3Label.Text = 'IC3:';
            app.IC4Label = uilabel(app.UIFigure);
            app.IC4Label.Text = 'IC4:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-';
            app.RollCentermmLabel = uilabel(app.UIFigure);
            app.RollCentermmLabel.Text = {'Roll Center '; '[mm]:'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-';
            app.DistancebetweenRCandCGmmLabel = uilabel(app.UIFigure);
            app.DistancebetweenRCandCGmmLabel.Text = {'Distance between '; 'RC and CG [mm] :'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '-';
            app.RCLabel = uilabel(app.UIFigure);
            app.RCLabel.Text = 'RC:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roll Center Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rollCenterCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end